classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MatrizButton      matlab.ui.control.Button
        CinematicaButton  matlab.ui.control.Button
        Theta5Slider      matlab.ui.control.Slider
        Theta5Label       matlab.ui.control.Label
        Theta4Slider      matlab.ui.control.Slider
        Theta4Label       matlab.ui.control.Label
        Theta3Slider      matlab.ui.control.Slider
        Theta3Label       matlab.ui.control.Label
        Theta2Slider      matlab.ui.control.Slider
        Theta2Label       matlab.ui.control.Label
        Theta1Slider      matlab.ui.control.Slider
        Theta1Label       matlab.ui.control.Label
        zEditField        matlab.ui.control.NumericEditField
        zEditFieldLabel   matlab.ui.control.Label
        yEditField        matlab.ui.control.NumericEditField
        yEditFieldLabel   matlab.ui.control.Label
        XEditField        matlab.ui.control.NumericEditField
        XEditFieldLabel   matlab.ui.control.Label
        LongitudmmLabel   matlab.ui.control.Label
        L5Spinner         matlab.ui.control.Spinner
        L5SpinnerLabel    matlab.ui.control.Label
        L4Spinner         matlab.ui.control.Spinner
        L4SpinnerLabel    matlab.ui.control.Label
        L3Spinner         matlab.ui.control.Spinner
        L3SpinnerLabel    matlab.ui.control.Label
        L2Spinner         matlab.ui.control.Spinner
        L2SpinnerLabel    matlab.ui.control.Label
        LIBERTADLabel     matlab.ui.control.Label
        L1Spinner         matlab.ui.control.Spinner
        L1SpinnerLabel    matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

        % =========================================================
    % PROPIEDADES INTERNAS DEL ROBOT
    % =========================================================
    properties (Access = private)

        theta1Actual = 0
        theta2Actual = 45
        theta3Actual = 0
        theta4Actual = 0
        theta5Actual = 0

    end
    methods (Access = private)
      
       function actualizarRobot(app, theta1, theta2, theta3, theta4, ~)

    % =========================================================
    % LONGITUDES
    % =========================================================

    L1 = app.L1Spinner.Value;
    L2 = app.L2Spinner.Value;
    L3 = app.L3Spinner.Value;
    L4 = app.L4Spinner.Value;
    L5 = app.L5Spinner.Value;


    % =========================================================
    % PUNTOS DEL BRAZO
    % =========================================================

    P0 = [0; 0; 0];


    % =========================================================
    % PRIMER ESLABON
    % =========================================================

    P1 = [0;
          0;
          L1];


    % =========================================================
    % SEGUNDO ESLABON
    % =========================================================

    alpha2 = theta2;

    r2 = L2*cosd(alpha2);

    z2 = L1 + L2*sind(alpha2);

    P2 = [r2*cosd(theta1);
          r2*sind(theta1);
          z2];


    % =========================================================
    % TERCER ESLABON
    % =========================================================

    alpha3 = theta2 + theta3;

    r3 = r2 + L3*cosd(alpha3);

    z3 = z2 + L3*sind(alpha3);

    P3 = [r3*cosd(theta1);
          r3*sind(theta1);
          z3];


    % =========================================================
    % CUARTO ESLABON
    % =========================================================

    alpha4 = theta2 + theta3 + theta4;

    r4 = r3 + L4*cosd(alpha4);

    z4 = z3 + L4*sind(alpha4);

    P4 = [r4*cosd(theta1);
          r4*sind(theta1);
          z4];


    % =========================================================
    % QUINTO ESLABON / EFECTOR FINAL
    % =========================================================

    r5 = r4 + L5*cosd(alpha4);

    z5 = z4 + L5*sind(alpha4);

    P5 = [r5*cosd(theta1);
          r5*sind(theta1);
          z5];


    % =========================================================
    % COORDENADAS DEL EFECTOR
    % =========================================================

    app.XEditField.Value = P5(1);

    app.yEditField.Value = P5(2);

    app.zEditField.Value = P5(3);


    % =========================================================
    % VECTORES PARA GRAFICAR
    % =========================================================

    X = [P0(1) P1(1) P2(1) P3(1) P4(1) P5(1)];

    Y = [P0(2) P1(2) P2(2) P3(2) P4(2) P5(2)];

    Z = [P0(3) P1(3) P2(3) P3(3) P4(3) P5(3)];


    % =========================================================
    % LIMPIAR GRAFICA
    % =========================================================

    cla(app.UIAxes);


    % =========================================================
    % DIBUJAR BRAZO
    % =========================================================

    plot3(app.UIAxes,...
        X,...
        Y,...
        Z,...
        '-o',...
        'LineWidth',3,...
        'MarkerSize',8,...
        'MarkerFaceColor',[0.85 0.33 0.10]);


    hold(app.UIAxes,'on');


    % =========================================================
    % BASE
    % =========================================================

    plot3(app.UIAxes,...
        P0(1),P0(2),P0(3),...
        'o',...
        'MarkerSize',12,...
        'MarkerFaceColor',[1 0.8 0],...
        'MarkerEdgeColor',[1 0.8 0]);


    % =========================================================
    % EFECTOR FINAL
    % =========================================================

    plot3(app.UIAxes,...
        P5(1),P5(2),P5(3),...
        'o',...
        'MarkerSize',12,...
        'MarkerFaceColor',[0 1 0],...
        'MarkerEdgeColor',[0 1 0]);


    % =========================================================
    % EJES CARTESIANOS
    % =========================================================

    alcance = L1 + L2 + L3 + L4 + L5;


    plot3(app.UIAxes,...
        [0 alcance],[0 0],[0 0],...
        'r',...
        'LineWidth',1.5);


    plot3(app.UIAxes,...
        [0 0],[0 alcance],[0 0],...
        'g',...
        'LineWidth',1.5);


    plot3(app.UIAxes,...
        [0 0],[0 0],[0 alcance],...
        'b',...
        'LineWidth',1.5);


    % =========================================================
    % CONFIGURACION
    % =========================================================

    grid(app.UIAxes,'on');

    xlabel(app.UIAxes,'X');

    ylabel(app.UIAxes,'Y');

    zlabel(app.UIAxes,'Z');

    title(app.UIAxes,'BRAZO DE 5 ARTICULACIONES');

    axis(app.UIAxes,'equal');

    xlim(app.UIAxes,[-alcance alcance]);

    ylim(app.UIAxes,[-alcance alcance]);

    zlim(app.UIAxes,[0 alcance]);

    view(app.UIAxes,3);

    hold(app.UIAxes,'off');

       end
       function animarRobot(app)

    % =========================================================
    % POSICION ACTUAL
    % =========================================================

    inicio = [...
        app.theta1Actual,...
        app.theta2Actual,...
        app.theta3Actual,...
        app.theta4Actual,...
        app.theta5Actual];


    % =========================================================
    % POSICION FINAL DE LOS SLIDERS
    % =========================================================

    destino = [...
        app.Theta1Slider.Value,...
        app.Theta2Slider.Value,...
        app.Theta3Slider.Value,...
        app.Theta4Slider.Value,...
        app.Theta5Slider.Value];


    % =========================================================
    % NUMERO DE PASOS
    % =========================================================

    pasos = 40;


    % =========================================================
    % ANIMACION
    % =========================================================

    for i = 1:pasos

        porcentaje = i / pasos;


        % Interpolacion lineal

        theta = inicio + ...
            (destino - inicio) * porcentaje;


        % Dibujar posicion actual

        app.actualizarRobot(...
            theta(1),...
            theta(2),...
            theta(3),...
            theta(4),...
            theta(5));


        % Actualizar pantalla

        drawnow;


        % Velocidad de animacion

        pause(0.02);

    end


    % =========================================================
    % GUARDAR POSICION FINAL
    % =========================================================

    app.theta1Actual = destino(1);

    app.theta2Actual = destino(2);

    app.theta3Actual = destino(3);

    app.theta4Actual = destino(4);

    app.theta5Actual = destino(5);

end
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    % =========================================================
    % LONGITUDES
    % =========================================================

    app.L1Spinner.Value = 100;
    app.L2Spinner.Value = 100;
    app.L3Spinner.Value = 80;
    app.L4Spinner.Value = 60;
    app.L5Spinner.Value = 40;


    % =========================================================
    % ANGULOS INICIALES
    % =========================================================

    app.Theta1Slider.Value = 0;

    app.Theta2Slider.Value = 45;

    app.Theta3Slider.Value = 0;

    app.Theta4Slider.Value = 0;

    app.Theta5Slider.Value = 0;


    % =========================================================
    % POSICION INICIAL DEL BRAZO
    % =========================================================

    app.theta1Actual = app.Theta1Slider.Value;

    app.theta2Actual = app.Theta2Slider.Value;

    app.theta3Actual = app.Theta3Slider.Value;

    app.theta4Actual = app.Theta4Slider.Value;

    app.theta5Actual = app.Theta5Slider.Value;


    % =========================================================
    % DIBUJAR POSICION INICIAL
    % =========================================================

    app.actualizarRobot(...
        app.theta1Actual,...
        app.theta2Actual,...
        app.theta3Actual,...
        app.theta4Actual,...
        app.theta5Actual);

        end

        % Callback function: not associated with a component
        function CinemticaGeomtricaCheckBoxValueChanged(app, event)
 app.CinemticaGeomtricaCheckBox.Value = 1;

    app.MatrizdeTransformacinCheckBox.Value = 0;


    % =========================================================
    % DIBUJAR BRAZO
    % =========================================================

    app.actualizarRobot();
        end

        % Callback function: not associated with a component
        function MatrizdeTransformacinCheckBoxValueChanged(app, event)
    if app.CinemticaGeomtricaCheckBox.Value == 1

        app.MatrizdeTransformacinCheckBox.Value = 0;

    else

        app.MatrizdeTransformacinCheckBox.Value = 1;

    end

    app.actualizarRobot();           
        end

        % Callback function
        function Theta1SliderValueChanged(app, event)

        end

        % Value changed function: L1Spinner
        function L1SpinnerValueChanged(app, event)
              
        end

        % Callback function
        function Theta2SliderValueChanged(app, event)
          
        end

        % Callback function
        function Theta3SliderValueChanged(app, event)
   
        end

        % Callback function
        function Theta4SliderValueChanged(app, event)
   
        end

        % Callback function
        function Theta5SliderValueChanged(app, event)

        end

        % Value changed function: L2Spinner
        function L2SpinnerValueChanged(app, event)

        end

        % Value changed function: L3Spinner
        function L3SpinnerValueChanged(app, event)
          
        end

        % Value changed function: L4Spinner
        function L4SpinnerValueChanged(app, event)
       
        end

        % Value changed function: L5Spinner
        function L5SpinnerValueChanged(app, event)
        
        end

        % Button pushed function: CinematicaButton
        function CinematicaButtonPushed(app, event)
             app.animarRobot();
        end

        % Button pushed function: MatrizButton
        function MatrizButtonPushed(app, event)
             app.animarRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BRACITO')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.L1SpinnerLabel = uilabel(app.UIFigure);
            app.L1SpinnerLabel.Text = 'L1';
            app.L1Spinner = uispinner(app.UIFigure);
            app.L1Spinner.ValueChangedFcn = createCallbackFcn(app, @L1SpinnerValueChanged, true);
            app.LIBERTADLabel = uilabel(app.UIFigure);
            app.LIBERTADLabel.Text = 'LIBERTAD';
            app.L2SpinnerLabel = uilabel(app.UIFigure);
            app.L2SpinnerLabel.Text = 'L2';
            app.L2Spinner = uispinner(app.UIFigure);
            app.L2Spinner.ValueChangedFcn = createCallbackFcn(app, @L2SpinnerValueChanged, true);
            app.L3SpinnerLabel = uilabel(app.UIFigure);
            app.L3SpinnerLabel.Text = 'L3';
            app.L3Spinner = uispinner(app.UIFigure);
            app.L3Spinner.ValueChangedFcn = createCallbackFcn(app, @L3SpinnerValueChanged, true);
            app.L4SpinnerLabel = uilabel(app.UIFigure);
            app.L4SpinnerLabel.Text = 'L4';
            app.L4Spinner = uispinner(app.UIFigure);
            app.L4Spinner.ValueChangedFcn = createCallbackFcn(app, @L4SpinnerValueChanged, true);
            app.L5SpinnerLabel = uilabel(app.UIFigure);
            app.L5SpinnerLabel.Text = 'L5';
            app.L5Spinner = uispinner(app.UIFigure);
            app.L5Spinner.ValueChangedFcn = createCallbackFcn(app, @L5SpinnerValueChanged, true);
            app.LongitudmmLabel = uilabel(app.UIFigure);
            app.LongitudmmLabel.Text = 'Longitud (mm)';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1Label = uilabel(app.UIFigure);
            app.Theta1Label.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.Value = 180;
            app.Theta2Label = uilabel(app.UIFigure);
            app.Theta2Label.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [0 180];
            app.Theta2Slider.Value = 180;
            app.Theta3Label = uilabel(app.UIFigure);
            app.Theta3Label.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-135 135];
            app.Theta3Slider.Value = 135;
            app.Theta4Label = uilabel(app.UIFigure);
            app.Theta4Label.Text = 'Theta 4';
            app.Theta4Slider = uislider(app.UIFigure);
            app.Theta4Slider.Limits = [-90 90];
            app.Theta4Slider.Value = 90;
            app.Theta5Label = uilabel(app.UIFigure);
            app.Theta5Label.Text = 'Theta 5';
            app.Theta5Slider = uislider(app.UIFigure);
            app.Theta5Slider.Limits = [-180 180];
            app.Theta5Slider.Value = 180;
            app.CinematicaButton = uibutton(app.UIFigure, 'push');
            app.CinematicaButton.ButtonPushedFcn = createCallbackFcn(app, @CinematicaButtonPushed, true);
            app.CinematicaButton.Text = 'Cinemática Geométrica';
            app.MatrizButton = uibutton(app.UIFigure, 'push');
            app.MatrizButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizButtonPushed, true);
            app.MatrizButton.Text = 'Matriz de Transformación';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end