classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GradosEditField       matlab.ui.control.NumericEditField
        GradosEditFieldLabel  matlab.ui.control.Label
        MetrosEditField       matlab.ui.control.NumericEditField
        MetrosEditFieldLabel  matlab.ui.control.Label
        EXTRINSECAButton      matlab.ui.control.Button
        INTRINSECAButton      matlab.ui.control.Button
        ROTACINLabel          matlab.ui.control.Label
        TRANSLACIONLabel      matlab.ui.control.Label
        Rz                    matlab.ui.control.Button
        Ry                    matlab.ui.control.Button
        Rx                    matlab.ui.control.Button
        Tz                    matlab.ui.control.Button
        Ty                    matlab.ui.control.Button
        Tx                    matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    MatrizCaja      % Guarda las coordenadas de los puntos del cubo (4x8)
    ModoMovimiento  % 'Extrinseca' o 'Intrinseca'
end

methods (Access = private)


    % Función encargada de graficar en la interfaz
    function Dibujacaja_GUI(app, PointMatrix)
        % Limpiar el gráfico anterior
        cla(app.UIAxes);

        % Extraer vértices
        P1 = PointMatrix(:,1); P2 = PointMatrix(:,2); 
        P3 = PointMatrix(:,3); P4 = PointMatrix(:,4);
        P5 = PointMatrix(:,5); P6 = PointMatrix(:,6); 
        P7 = PointMatrix(:,7); P8 = PointMatrix(:,8);

        % Trazar aristas en app.UIAxes
        % Base inferior
        plot3(app.UIAxes, [P1(1) P2(1)], [P1(2) P2(2)], [P1(3) P2(3)], 'w-', 'LineWidth', 2); hold(app.UIAxes, 'on');
        plot3(app.UIAxes, [P1(1) P3(1)], [P1(2) P3(2)], [P1(3) P3(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P2(1) P4(1)], [P2(2) P4(2)], [P2(3) P4(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P3(1) P4(1)], [P3(2) P4(2)], [P3(3) P4(3)], 'w-', 'LineWidth', 2);

        % Base superior
        plot3(app.UIAxes, [P5(1) P6(1)], [P5(2) P6(2)], [P5(3) P6(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P5(1) P7(1)], [P5(2) P7(2)], [P5(3) P7(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P6(1) P8(1)], [P6(2) P8(2)], [P6(3) P8(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P7(1) P8(1)], [P7(2) P7(2)], [P7(3) P8(3)], 'w-', 'LineWidth', 2);

        % Columnas verticales
        plot3(app.UIAxes, [P1(1) P5(1)], [P1(2) P5(2)], [P1(3) P5(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P2(1) P6(1)], [P2(2) P6(2)], [P2(3) P6(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P3(1) P7(1)], [P3(2) P7(2)], [P3(3) P7(3)], 'w-', 'LineWidth', 2);
        plot3(app.UIAxes, [P4(1) P8(1)], [P4(2) P8(2)], [P4(3) P8(3)], 'w-', 'LineWidth', 2);

        % Configuración de los ejes y vista
        grid(app.UIAxes, 'on');
        axis(app.UIAxes, 'equal');
        app.UIAxes.XLim = [-10 10];
        app.UIAxes.YLim = [-10 10];
        app.UIAxes.ZLim = [-10 10];
        view(app.UIAxes, 45, 30);
    end

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Definir el modo de movimiento por defecto
app.ModoMovimiento = 'Extrinseca';

% 2. Crear la matriz inicial de la caja (2x2x2 en el origen)
app.MatrizCaja = Dibujarmatriz(2, 2, 2, 0, 0, 0); 

% 3. Configurar el plano cartesiano UIAxes para 3D
view(app.UIAxes, 45, 30);
axis(app.UIAxes, 'equal');
grid(app.UIAxes, 'on');
        end

        % Button pushed function: EXTRINSECAButton
        function EXTRINSECAButtonPushed(app, event)
            app.ModoMovimiento = 'Extrinseca';
        end

        % Button pushed function: INTRINSECAButton
        function INTRINSECAButtonPushed(app, event)
            app.ModoMovimiento = 'Intrinseca';
        end

        % Button pushed function: Rx
        function RxButtonPushed(app, event)
            % 1. Leer los grados
theta = app.GradosEditField.Value;

% 2. Crear matriz de rotación en X
RotX = [1 0 0 0; 
      0 cosd(theta) -sind(theta) 0; 
      0 sind(theta) cosd(theta) 0; 
      0 0 0 1];

% 3. Aplicar rotación según el modo
if strcmp(app.ModoMovimiento, 'Extrinseca')
    app.MatrizCaja = RotX * app.MatrizCaja;
else
    % Intrínseca: Rotar sobre el propio centro de la caja
    cx = mean(app.MatrizCaja(1, :));
    cy = mean(app.MatrizCaja(2, :));
    cz = mean(app.MatrizCaja(3, :));

    T_origen = [1 0 0 -cx; 0 1 0 -cy; 0 0 1 -cz; 0 0 0 1];
    T_vuelta = [1 0 0 cx; 0 1 0 cy; 0 0 1 cz; 0 0 0 1];

    app.MatrizCaja = T_vuelta * RotX * T_origen * app.MatrizCaja;
end

% 4. Redibujar
app.Dibujacaja_GUI(app.MatrizCaja);
        end

        % Button pushed function: Ry
        function RyButtonPushed(app, event)
          % 1. Leer los grados
theta = app.GradosEditField.Value;

% 2. Crear matriz de rotación en X
RotY = [cosd(theta) 0 sind(theta) 0; 
        0 1 0 0; 
       -sind(theta) 0 cosd(theta) 0; 
        0 0 0 1];

% 3. Aplicar rotación según el modo
if strcmp(app.ModoMovimiento, 'Extrinseca')
    app.MatrizCaja = RotY * app.MatrizCaja;
else
    % Intrínseca: Rotar sobre el propio centro de la caja
    cx = mean(app.MatrizCaja(1, :));
    cy = mean(app.MatrizCaja(2, :));
    cz = mean(app.MatrizCaja(3, :));

    T_origen = [1 0 0 -cx; 0 1 0 -cy; 0 0 1 -cz; 0 0 0 1];
    T_vuelta = [1 0 0 cx; 0 1 0 cy; 0 0 1 cz; 0 0 0 1];

    app.MatrizCaja = T_vuelta * RotY * T_origen * app.MatrizCaja;
end

% 4. Redibujar
app.Dibujacaja_GUI(app.MatrizCaja);
        end

        % Button pushed function: Rz
        function RzButtonPushed(app, event)
            % 1. Leer los grados
theta = app.GradosEditField.Value;

% 2. Crear matriz de rotación en X
RotZ = [cosd(theta) -sind(theta) 0 0; 
        sind(theta) cosd(theta) 0 0; 
        0 0 1 0; 
        0 0 0 1];

% 3. Aplicar rotación según el modo
if strcmp(app.ModoMovimiento, 'Extrinseca')
    app.MatrizCaja = RotZ * app.MatrizCaja;
else
    % Intrínseca: Rotar sobre el propio centro de la caja
    cx = mean(app.MatrizCaja(1, :));
    cy = mean(app.MatrizCaja(2, :));
    cz = mean(app.MatrizCaja(3, :));

    T_origen = [1 0 0 -cx; 0 1 0 -cy; 0 0 1 -cz; 0 0 0 1];
    T_vuelta = [1 0 0 cx; 0 1 0 cy; 0 0 1 cz; 0 0 0 1];

    app.MatrizCaja = T_vuelta * RotZ * T_origen * app.MatrizCaja;
end

% 4. Redibujar
app.Dibujacaja_GUI(app.MatrizCaja);
        end

        % Button pushed function: Tx
        function TxButtonPushed(app, event)
            % 1. Leer los metros a mover
d = app.MetrosEditField.Value;

% 2. Crear matriz de traslación en X
TrasX = [1 0 0 d; 
         0 1 0 0; 
         0 0 1 0; 
         0 0 0 1];

% 3. Aplicar traslación a los puntos de la caja
app.MatrizCaja = TrasX * app.MatrizCaja;

% 4. Redibujar
app.Dibujacaja_GUI(app.MatrizCaja);
        end

        % Button pushed function: Ty
        function TyButtonPushed(app, event)
            d = app.MetrosEditField.Value;

% Matriz de traslación en Y
TrasY = [1 0 0 0; 
         0 1 0 d; 
         0 0 1 0; 
         0 0 0 1];

app.MatrizCaja = TrasY * app.MatrizCaja;
app.Dibujacaja_GUI(app.MatrizCaja);
        end

        % Button pushed function: Tz
        function TzButtonPushed(app, event)
            d = app.MetrosEditField.Value;

% Matriz de traslación en Z
TrasZ = [1 0 0 0; 
         0 1 0 0; 
         0 0 1 d; 
         0 0 0 1];

app.MatrizCaja = TrasZ * app.MatrizCaja;
app.Dibujacaja_GUI(app.MatrizCaja);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tx = uibutton(app.UIFigure, 'push');
            app.Tx.ButtonPushedFcn = createCallbackFcn(app, @TxButtonPushed, true);
            app.Tx.Text = 'X';
            app.Ty = uibutton(app.UIFigure, 'push');
            app.Ty.ButtonPushedFcn = createCallbackFcn(app, @TyButtonPushed, true);
            app.Ty.Text = 'Y';
            app.Tz = uibutton(app.UIFigure, 'push');
            app.Tz.ButtonPushedFcn = createCallbackFcn(app, @TzButtonPushed, true);
            app.Tz.Text = 'Z';
            app.Rx = uibutton(app.UIFigure, 'push');
            app.Rx.ButtonPushedFcn = createCallbackFcn(app, @RxButtonPushed, true);
            app.Rx.Text = 'X';
            app.Ry = uibutton(app.UIFigure, 'push');
            app.Ry.ButtonPushedFcn = createCallbackFcn(app, @RyButtonPushed, true);
            app.Ry.Text = 'Y';
            app.Rz = uibutton(app.UIFigure, 'push');
            app.Rz.ButtonPushedFcn = createCallbackFcn(app, @RzButtonPushed, true);
            app.Rz.Text = 'Z';
            app.TRANSLACIONLabel = uilabel(app.UIFigure);
            app.TRANSLACIONLabel.Text = 'TRANSLACION';
            app.ROTACINLabel = uilabel(app.UIFigure);
            app.ROTACINLabel.Text = 'ROTACIÓN';
            app.INTRINSECAButton = uibutton(app.UIFigure, 'push');
            app.INTRINSECAButton.ButtonPushedFcn = createCallbackFcn(app, @INTRINSECAButtonPushed, true);
            app.INTRINSECAButton.Text = 'INTRINSECA';
            app.EXTRINSECAButton = uibutton(app.UIFigure, 'push');
            app.EXTRINSECAButton.ButtonPushedFcn = createCallbackFcn(app, @EXTRINSECAButtonPushed, true);
            app.EXTRINSECAButton.Text = 'EXTRINSECA';
            app.MetrosEditFieldLabel = uilabel(app.UIFigure);
            app.MetrosEditFieldLabel.Text = 'Metros';
            app.MetrosEditField = uieditfield(app.UIFigure, 'numeric');
            app.GradosEditFieldLabel = uilabel(app.UIFigure);
            app.GradosEditFieldLabel.Text = 'Grados';
            app.GradosEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end